classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureDesignerMultiEquationGraphingNumericalUtility  matlab.ui.Figure
        ThemeSwitch             matlab.ui.control.Switch
        ThemeSwitchLabel        matlab.ui.control.Label
        ExportButton            matlab.ui.control.Button
        WireframeCheckBox       matlab.ui.control.CheckBox
        ColormapDropDown        matlab.ui.control.DropDown
        DColormapDropDownLabel  matlab.ui.control.Label
        EnableEq2CheckBox       matlab.ui.control.CheckBox
        Function2EditField      matlab.ui.control.EditField
        Equation1Label_2        matlab.ui.control.Label
        RootResultLabel         matlab.ui.control.Label
        FindRootsButton         matlab.ui.control.Button
        ZMinField               matlab.ui.control.NumericEditField
        YMinLabel_2             matlab.ui.control.Label
        FormatHintLabel         matlab.ui.control.Label
        ZMaxField               matlab.ui.control.NumericEditField
        YMaxLabel_2             matlab.ui.control.Label
        DimensionSwitch         matlab.ui.control.Switch
        AddColorButton          matlab.ui.control.Button
        ColorEditField          matlab.ui.control.EditField
        ColorDropDown           matlab.ui.control.DropDown
        LineColorDropDownLabel  matlab.ui.control.Label
        ResetViewButton         matlab.ui.control.Button
        StatusLabel             matlab.ui.control.Label
        StatusTextLabel         matlab.ui.control.Label
        YMaxField               matlab.ui.control.NumericEditField
        YMaxLabel               matlab.ui.control.Label
        YMinField               matlab.ui.control.NumericEditField
        YMinLabel               matlab.ui.control.Label
        XMaxField               matlab.ui.control.NumericEditField
        XMaxLabel               matlab.ui.control.Label
        XMinField               matlab.ui.control.NumericEditField
        XMinLabel               matlab.ui.control.Label
        FunctionEditField       matlab.ui.control.EditField
        Equation1Label          matlab.ui.control.Label
        GridCheckBox            matlab.ui.control.CheckBox
        HoldOnCheckBox          matlab.ui.control.CheckBox
        ClearButton             matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = public)  
        AutoColorIndex = 1 % Tracks auto-cycling position (1 to 4)
    end
    methods (Access = private)
        % Code that executes after component creation
        function startupFcn(app)
            % Define standard 4-color order on axes start
            app.UIAxes.ColorOrder = [
                0.00, 0.45, 0.74; % Blue
                1.00, 0.00, 0.00; % Pure Red
                0.93, 0.69, 0.13; % Yellow
                0.49, 0.18, 0.56  % Purple
            ];
            
            app.applyTheme(app.ThemeSwitch.Value);
        end
        function applyTheme(app, theme)
            if strcmp(theme, 'Dark')
                % --- Dark Theme Palette ---
                bgColor      = [0.12, 0.12, 0.12]; % Figure background
                panelBgColor = [0.18, 0.18, 0.18]; % Panel/Inputs background
                btnBgColor   = [0.25, 0.25, 0.25]; % Button background
                textColor    = [0.95, 0.95, 0.95]; % Light text
                axesBgColor  = [0.15, 0.15, 0.15]; % Plot area background
                gridColor    = [0.80, 0.80, 0.80]; % Light grid
            else
                % --- Light Theme Palette ---
                bgColor      = [0.94, 0.94, 0.94]; % Light background
                panelBgColor = [1.00, 1.00, 1.00]; % White background
                btnBgColor   = [0.88, 0.88, 0.88]; % Light gray button
                textColor    = [0.10, 0.10, 0.10]; % Dark text
                axesBgColor  = [1.00, 1.00, 1.00]; % White plot area
                gridColor    = [0.20, 0.20, 0.20]; % Dark grid
            end

            % 1. Main Window
            app.UIFigureDesignerMultiEquationGraphingNumericalUtility.Color = bgColor;

            % 2. 3D & 2D Axes Colors
            app.UIAxes.Color           = axesBgColor;
            app.UIAxes.BackgroundColor = axesBgColor;
            app.UIAxes.XColor          = textColor;
            app.UIAxes.YColor          = textColor;
            app.UIAxes.ZColor          = textColor;
            app.UIAxes.GridColor       = gridColor;

            % 3. Text Labels & Checkboxes
            labels = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uilabel');
            set(labels, 'FontColor', textColor);

            checkboxes = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uicheckbox');
            set(checkboxes, 'FontColor', textColor);

            % 4. Fix Toggle Switch Text
            switches = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uiswitch');
            for k = 1:numel(switches)
                if isprop(switches(k), 'FontColor')
                    switches(k).FontColor = textColor;
                end
            end

            % 5. Buttons, Inputs & Dropdowns
            buttons = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uibutton');
            set(buttons, 'BackgroundColor', btnBgColor, 'FontColor', textColor);

            editFields = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uieditfield');
            set(editFields, 'BackgroundColor', panelBgColor, 'FontColor', textColor);

            numFields = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uinumericeditfield');
            set(numFields, 'BackgroundColor', panelBgColor, 'FontColor', textColor);

            dropdowns = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uidropdown');
            set(dropdowns, 'BackgroundColor', panelBgColor, 'FontColor', textColor);

            panels = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'uipanel');
            set(panels, 'BackgroundColor', panelBgColor, 'ForegroundColor', textColor);

            % 6. Status Label
            if contains(app.StatusLabel.Text, 'Error')
                app.StatusLabel.FontColor = [0.8 0 0];
            else
                app.StatusLabel.FontColor = textColor;
            end
            % Update Legend Theme Colors
            lgd = app.UIAxes.Legend;
            if ~isempty(lgd)
                lgd.TextColor = textColor;
                lgd.Color = panelBgColor;
                lgd.EdgeColor = gridColor;
            end
        end
        function points = findIntersections(app, exprStr1, exprStr2, xLimits)
            points = [];
            try
                syms x
                symExpr1 = str2sym(exprStr1);

                % Handle implicit equation 1 (LHS = RHS -> LHS - RHS = 0)
                if contains(exprStr1, '=')
                    parts = split(exprStr1, '=');
                    symExpr1 = str2sym(parts{1}) - str2sym(parts{2});
                end

                if isempty(exprStr2)
                    % Mode A: Root Finding (f1(x) = 0)
                    diffExpr = symExpr1;
                else
                    % Mode B: Intersection Finding (f1(x) - f2(x) = 0)
                    symExpr2 = str2sym(exprStr2);
                    if contains(exprStr2, '=')
                        parts2 = split(exprStr2, '=');
                        symExpr2 = str2sym(parts2{1}) - str2sym(parts2{2});
                    end
                    diffExpr = symExpr1 - symExpr2;
                end

                % Solve numerically for X within limits
                solX = vpasolve(diffExpr == 0, x, xLimits);
                xVals = double(solX);

                % Keep only real roots within X limits
                xVals = xVals(isreal(xVals) & xVals >= xLimits(1) & xVals <= xLimits(2));
                xVals = unique(round(xVals, 4));

                % Calculate corresponding Y values on f1(x)
                for i = 1:length(xVals)
                    xSol = xVals(i);
                    ySol = double(subs(symExpr1, x, xSol));

                    % Handle explicit vs implicit y evaluation
                    if ~contains(exprStr1, 'y')
                        yVal = double(subs(str2sym(exprStr1), x, xSol));
                    else
                        yVal = 0; % Fallback for implicit curves
                    end
                    points = [points; xSol, yVal]; %#ok<AGROW>
                end
            catch
                points = [];
            end
        end
    end
    methods (Access = private)

        % Helper 1: Convert user text into valid MATLAB function string
        function cleanStr = formatEquation(app, rawStr)
            cleanStr = strtrim(rawStr);

            % Fix equal signs: "f(x,y) = 0" -> "(f(x,y)) - (0)"
            if contains(cleanStr, '=')
                parts = split(cleanStr, '=');
                cleanStr = sprintf('(%s) - (%s)', parts{1}, parts{2});
            end

            % Add implicit multiplication between numbers and variables: "4x" -> "4*x"
            cleanStr = regexprep(cleanStr, '(\d)([a-zA-Z])', '$1*$2');
            cleanStr = regexprep(cleanStr, '([a-zA-Z])(\d)', '$1*$2');

            % Add implicit multiplication between closing parens and variables: "(x+1)y" -> "(x+1)*y"
            cleanStr = regexprep(cleanStr, '\)([\w])', '\)*$1');

            % Convert standard operators to array operators for element-wise evaluation
            cleanStr = regexprep(cleanStr, '(?<!\.)\*', '.*');
            cleanStr = regexprep(cleanStr, '(?<!\.)\/', './');
            cleanStr = regexprep(cleanStr, '(?<!\.)\^', '.^');
        end

        % Helper 2: Convert dropdown choices / hex strings to RGB vectors
        function rgb = parseColor(app, colorInput)
            switch lower(strtrim(colorInput))
                case 'blue',   rgb = [0, 0.4470, 0.7410];
                case 'red',    rgb = [0.8500, 0.3250, 0.0980];
                case 'green',  rgb = [0.4660, 0.6740, 0.1880];
                case 'black',  rgb = [0, 0, 0];
                otherwise
                    % Handle hex strings (e.g., "#FF5733")
                    if startsWith(colorInput, '#')
                        hex = erase(colorInput, '#');
                        if length(hex) == 6
                            rgb = [hex2dec(hex(1:2)), hex2dec(hex(3:4)), hex2dec(hex(5:6))] / 255;
                            return;
                        end
                    end
                    % Warn user about unrecognized color name
                    app.StatusLabel.Text = ['Unknown color: "', colorInput, '" — using default blue.'];
                    app.StatusLabel.FontColor = [0.8 0.5 0];
                    rgb = [0, 0.4470, 0.7410]; % Default fallback blue
            end
        end

    end
    methods (Access = private)
        function plotSingle2DEquation(app, rawExpr, xLimits, yLimits, lineColor)
            cleanExpr = app.formatEquation(rawExpr);
            hasY = contains(cleanExpr, 'y');
            if hasY
                f_implicit = str2func(['@(x,y) ' cleanExpr]);
                p = fimplicit(app.UIAxes, f_implicit, [xLimits(1), xLimits(2), yLimits(1), yLimits(2)]);
                p.LineWidth = 2;
                p.Color = lineColor;
            else
                f_explicit = str2func(['@(x) ' cleanExpr]);
                p = fplot(app.UIAxes, f_explicit, xLimits);
                p.LineWidth = 2;
                p.Color = lineColor;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

           try
                % Reset status display
                app.StatusLabel.Text = 'Processing...';
                app.StatusLabel.FontColor = [0 0 0];

                % 1. Get and sanitize Equation 1 input
                rawInput1 = strtrim(app.FunctionEditField.Value);
                if isempty(rawInput1)
                    app.StatusLabel.Text = 'Please enter an equation for Equation 1.';
                    app.StatusLabel.FontColor = [0.8 0 0];
                    return;
                end

                % 2. Read and Validate Axis Limits
                if app.XMinField.Value >= app.XMaxField.Value
                    app.StatusLabel.Text = 'Error: X Min must be strictly less than X Max.';
                    app.StatusLabel.FontColor = [0.8 0 0];
                    return;
                end
                if app.YMinField.Value >= app.YMaxField.Value
                    app.StatusLabel.Text = 'Error: Y Min must be strictly less than Y Max.';
                    app.StatusLabel.FontColor = [0.8 0 0];
                    return;
                end

                xLimits = [app.XMinField.Value, app.XMaxField.Value];
                yLimits = [app.YMinField.Value, app.YMaxField.Value];

                is3D = strcmp(app.DimensionSwitch.Value, '3D');
                if is3D
                    if app.ZMinField.Value >= app.ZMaxField.Value
                        app.StatusLabel.Text = 'Error: Z Min must be strictly less than Z Max.';
                        app.StatusLabel.FontColor = [0.8 0 0];
                        return;
                    end
                    zLimits = [app.ZMinField.Value, app.ZMaxField.Value];
                else
                    zLimits = [-10, 10];
                end

                % 3. Check Equation 2 validation (Always active if text exists)
                rawInput2 = strtrim(app.Function2EditField.Value);
                hasEq2Text = ~isempty(rawInput2);

                % 4. Determine Line Color (Fixed 4-Color Auto-Cycle vs Manual)
                colorStr = app.ColorDropDown.Value;
                isOverlay = isprop(app, 'HoldOnCheckBox') && app.HoldOnCheckBox.Value;

                % Define exact 4-color palette matrix [R, G, B]
                customPalette = [
                    0.00, 0.45, 0.74; % 1. Blue
                    1.00, 0.00, 0.00; % 2. Pure Red
                    0.93, 0.69, 0.13; % 3. Gold/Yellow
                    0.49, 0.18, 0.56  % 4. Purple
                ];

                % If overlay is OFF, reset the auto-cycle sequence back to Blue
                if ~isOverlay
                    app.AutoColorIndex = 1;
                end

                % Determine line color for Equation 1
                if ~strcmpi(colorStr, 'Auto') && ~strcmpi(colorStr, 'Default')
                    % MANUAL OVERRIDE: Pick exact user-selected color
                    if strcmp(colorStr, 'Custom...')
                        colorStr = app.ColorEditField.Value;
                    end
                    lineColor1 = app.parseColor(colorStr);
                else
                    % AUTO CYCLE: Pull color directly using app property
                    lineColor1 = customPalette(app.AutoColorIndex, :);
                    % Step through index: 1 -> 2 -> 3 -> 4 -> 1
                    app.AutoColorIndex = mod(app.AutoColorIndex, 4) + 1;
                end

                % 5. Handle Overlay state
                if isOverlay
                    hold(app.UIAxes, 'on');
                else
                    hold(app.UIAxes, 'off');
                end

                % 6. Plotting Execution (2D vs 3D)
                if is3D
                    % --- 3D PLOTTING MODE ---
                    cleanExpr1 = app.formatEquation(rawInput1);
                    bounds3D = [xLimits(1), xLimits(2), yLimits(1), yLimits(2), zLimits(1), zLimits(2)];
                    
                    if contains(cleanExpr1, 'z')
                        f_imp3 = str2func(['@(x,y,z) ' cleanExpr1]);
                        p1 = fimplicit3(app.UIAxes, f_imp3, bounds3D);
                    else
                        f_exp3 = str2func(['@(x,y) ' cleanExpr1]);
                        p1 = fsurf(app.UIAxes, f_exp3, [xLimits(1), xLimits(2), yLimits(1), yLimits(2)]);
                    end
                    p1.DisplayName = ['Eq 1: ', rawInput1];

                    % Surface visual properties
                    if isprop(app, 'WireframeCheckBox') && app.WireframeCheckBox.Value
                        p1.EdgeColor = lineColor1;
                        p1.FaceColor = 'none';
                    else
                        p1.EdgeColor = 'none';
                        p1.FaceColor = 'interp';
                        p1.FaceAlpha = 0.85;
                    end

                    % Plot 3D Equation 2 if text is present
                    if hasEq2Text
                        hold(app.UIAxes, 'on');
                        cleanExpr2 = app.formatEquation(rawInput2);
                        
                        if contains(cleanExpr2, 'z')
                            f_imp3_2 = str2func(['@(x,y,z) ' cleanExpr2]);
                            p2 = fimplicit3(app.UIAxes, f_imp3_2, bounds3D);
                        else
                            f_exp3_2 = str2func(['@(x,y) ' cleanExpr2]);
                            p2 = fsurf(app.UIAxes, f_exp3_2, [xLimits(1), xLimits(2), yLimits(1), yLimits(2)]);
                        end
                        p2.DisplayName = ['Eq 2: ', rawInput2];
                        p2.FaceAlpha = 0.65;
                    end

                    if isprop(app, 'ColormapDropDown')
                        selectedMap = strtrim(app.ColormapDropDown.Value);
                        try
                            colormap(app.UIAxes, selectedMap);
                        catch
                            colormap(app.UIAxes, 'parula');
                        end
                        colorbar(app.UIAxes);
                    end
                    view(app.UIAxes, 3);
                    zlabel(app.UIAxes, 'z');
                    zlim(app.UIAxes, zLimits);
                else
                    % --- 2D PLOTTING MODE ---
                    cb = findobj(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'Type', 'ColorBar');
                    delete(cb);

                    % Plot Equation 1
                    cleanExpr1 = app.formatEquation(rawInput1);
                    if contains(cleanExpr1, 'y')
                        f_imp1 = str2func(['@(x,y) ' cleanExpr1]);
                        p1 = fimplicit(app.UIAxes, f_imp1, [xLimits(1), xLimits(2), yLimits(1), yLimits(2)]);
                    else
                        f_exp1 = str2func(['@(x) ' cleanExpr1]);
                        p1 = fplot(app.UIAxes, f_exp1, xLimits);
                    end
                    p1.LineWidth = 2;
                    p1.Color = lineColor1;
                    p1.DisplayName = ['Eq 1: ', rawInput1];

                    % Plot Equation 2 if text is present
                    if hasEq2Text
                        hold(app.UIAxes, 'on');
                        cleanExpr2 = app.formatEquation(rawInput2);
                        
                        % Cycle color for Eq 2
                        lineColor2 = customPalette(app.AutoColorIndex, :);
                        app.AutoColorIndex = mod(app.AutoColorIndex, 4) + 1;
                        
                        if contains(cleanExpr2, 'y')
                            f_imp2 = str2func(['@(x,y) ' cleanExpr2]);
                            p2 = fimplicit(app.UIAxes, f_imp2, [xLimits(1), xLimits(2), yLimits(1), yLimits(2)]);
                        else
                            f_exp2 = str2func(['@(x) ' cleanExpr2]);
                            p2 = fplot(app.UIAxes, f_exp2, xLimits);
                        end
                        p2.LineWidth = 2;
                        p2.Color = lineColor2;
                        p2.DisplayName = ['Eq 2: ', rawInput2];
                    end
                    view(app.UIAxes, 2);
                end

                % 7. Legend, Labels, and Visual Finishes
                legend(app.UIAxes, 'Location', 'northeast');
                xlim(app.UIAxes, xLimits);
                ylim(app.UIAxes, yLimits);
                title(app.UIAxes, ['Graph of: ', rawInput1]);
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'y');

                % Enforce grid state
                if app.GridCheckBox.Value
                    grid(app.UIAxes, 'on');
                else
                    grid(app.UIAxes, 'off');
                end

                % Update legend color scheme based on current theme
                lgd = get(app.UIAxes, 'Legend');
                if ~isempty(lgd) && isvalid(lgd)
                    if strcmp(app.ThemeSwitch.Value, 'Dark')
                        lgd.TextColor  = [0.95 0.95 0.95];
                        lgd.Color      = [0.18 0.18 0.18];
                        lgd.EdgeColor  = [0.80 0.80 0.80];
                    else
                        lgd.TextColor  = [0.10 0.10 0.10];
                        lgd.Color      = [1.00 1.00 1.00];
                        lgd.EdgeColor  = [0.20 0.20 0.20];
                    end
                end

                app.StatusLabel.Text = 'Plotted successfully!';
                app.StatusLabel.FontColor = [0 0.6 0];
            catch ME
                fprintf('Error: %s\n', ME.message);
                app.StatusLabel.Text = ['Error: ', ME.message];
                app.StatusLabel.FontColor = [0.8 0 0];
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes, 'reset');
            app.UIAxes.Position = [428 1 734 715];  % Restore original position

            % Reset custom 4-color tracking index
            app.AutoColorIndex = 1;

            if isprop(app, 'ThemeSwitch')
                app.applyTheme(app.ThemeSwitch.Value);
            end

            app.StatusLabel.Text = 'Axes cleared.';
            app.StatusLabel.FontColor = [0 0.6 0];
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)

            app.XMinField.Value = -10;
            app.XMaxField.Value = 10;
            app.YMinField.Value = -10;
            app.YMaxField.Value = 10;
            app.ZMinField.Value = -10;
            app.ZMaxField.Value = 10;
            xlim(app.UIAxes, [-10, 10]);
            ylim(app.UIAxes, [-10, 10]);
            zlim(app.UIAxes, [-10, 10]);
            if strcmp(app.DimensionSwitch.Value, '3D')
                view(app.UIAxes, 3);
            else
                view(app.UIAxes, 2);
            end
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            selectedValue = app.ColorDropDown.Value;

            if strcmp(selectedValue, 'Custom...')
                % Show the custom input field and save button
                app.ColorEditField.Visible = 'on';
                app.AddColorButton.Visible = 'on';
            else
                % Hide custom inputs for standard presets
                app.ColorEditField.Visible = 'off';
                app.AddColorButton.Visible = 'off';
            end

        end

        % Button pushed function: AddColorButton
        function AddColorButtonPushed(app, event)

            newColorStr = strtrim(app.ColorEditField.Value);

            if ~isempty(newColorStr)
                % Insert the new custom string right before "Custom..." in the dropdown list
                currentItems = app.ColorDropDown.Items;
                currentItems = [currentItems(1:end-1), {newColorStr}, {'Custom...'}];  % Fixed line

                app.ColorDropDown.Items = currentItems;
                app.ColorDropDown.Value = newColorStr;

                % Hide custom entry controls until "Custom..." is picked again
                app.ColorEditField.Visible = 'off';
                app.AddColorButton.Visible = 'off';

                app.StatusLabel.Text = ['Added color: ', newColorStr];
                app.StatusLabel.FontColor = [0 0.7 0];
            end
        end

        % Value changed function: DimensionSwitch
        function DimensionSwitchValueChanged(app, event)

            is3D = strcmp(app.DimensionSwitch.Value, '3D');
            % Toggle visibility of Z fields AND labels based on switch state
            app.ZMinField.Visible = is3D;
            app.ZMaxField.Visible = is3D;
            app.YMinLabel_2.Visible = is3D; % Z Min Label
            app.YMaxLabel_2.Visible = is3D; % Z Max Label
            % Update camera view on the axes
            if is3D
                view(app.UIAxes, 3);
            else
                view(app.UIAxes, 2);
            end

        end

        % Button pushed function: FindRootsButton
        function FindRootsButtonPushed(app, event)

            try
                rawInput1 = app.FunctionEditField.Value;
                xLimits = [app.XMinField.Value, app.XMaxField.Value];

                % Check if Eq2 components exist and are enabled
                hasEq2 = isprop(app, 'EnableEq2CheckBox') && app.EnableEq2CheckBox.Value && ...
                    isprop(app, 'Function2EditField') && ~isempty(app.Function2EditField.Value);

                if hasEq2
                    rawInput2 = app.Function2EditField.Value;
                    isIntersectionMode = true;
                else
                    rawInput2 = '';
                    isIntersectionMode = false;
                end

                % Calculate coordinates [X, Y] using RAW strings (not formatted)
                pts = app.findIntersections(rawInput1, rawInput2, xLimits);
                if isempty(pts)
                    % Context-aware error message
                    if isIntersectionMode
                        app.RootResultLabel.Text = 'No intersection points found in view.';
                    else
                        app.RootResultLabel.Text = 'No real roots (x-intercepts) found in view.';
                    end
                    app.RootResultLabel.FontColor = [0.8 0 0]; % Red
                else
                    hold(app.UIAxes, 'on');

                    if isIntersectionMode
                        resultText = 'Intersections: ';
                    else
                        resultText = 'Roots: ';
                    end

                    for i = 1:size(pts, 1)
                        px = pts(i, 1);
                        py = pts(i, 2);

                        % Draw orange scatter marker at key point
                        scatter(app.UIAxes, px, py, 70, 'filled', ...
                            'MarkerFaceColor', [1 0.5 0], 'MarkerEdgeColor', [0 0 0]);

                        % Add coordinate callout label
                        text(app.UIAxes, px, py + 0.4, sprintf(' (%.2f, %.2f)', px, py), ...
                            'FontSize', 10, 'FontWeight', 'bold', 'Color', [0.8 0.3 0]);

                        resultText = [resultText, sprintf('(%.2f, %.2f) ', px, py)]; %#ok<AGROW>
                    end

                    app.RootResultLabel.Text = resultText;
                    app.RootResultLabel.FontColor = [0 0.6 0]; % Green
                end

            catch ME
                app.RootResultLabel.Text = 'Error calculating points!';
                app.RootResultLabel.FontColor = [0.8 0 0];
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            try
                % Prompt user to choose file location and format
                [file, path] = uiputfile(...
                    {'*.png', 'PNG Image (*.png)'; ...
                    '*.jpg', 'JPEG Image (*.jpg)'; ...
                    '*.pdf', 'PDF Document (*.pdf)'}, ...
                    'Save Plot As', 'MyPlot.png');

                % If user cancels the dialog
                if isequal(file, 0) || isequal(path, 0)
                    app.StatusLabel.Text = 'Export cancelled.';
                    app.StatusLabel.FontColor = [0.5 0.5 0.5];
                    return;
                end

                fullFilePath = fullfile(path, file);

                % Export the UIAxes content
                exportgraphics(app.UIAxes, fullFilePath, 'Resolution', 300);

                app.StatusLabel.Text = ['Plot exported successfully to ', file];
                app.StatusLabel.FontColor = [0 0.6 0];
            catch ME
                app.StatusLabel.Text = ['Export Error: ', ME.message];
                app.StatusLabel.FontColor = [0.8 0 0];
            end
        end

        % Value changed function: ThemeSwitch
        function ThemeSwitchValueChanged(app, event)
            selectedTheme = app.ThemeSwitch.Value; % 'Dark' or 'Light'
            app.applyTheme(selectedTheme);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureDesignerMultiEquationGraphingNumericalUtility = uifigure('Visible', 'off');
            app.UIFigureDesignerMultiEquationGraphingNumericalUtility.Name = 'MATLAB App Designer Multi-Equation Graphing & Numerical Utility';
            app.UIAxes = uiaxes(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'PLOT GRAPH';
            app.ClearButton = uibutton(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'CLEAR';
            app.HoldOnCheckBox = uicheckbox(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.HoldOnCheckBox.Text = 'Overlay Plots (Hold On)';
            app.GridCheckBox = uicheckbox(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.GridCheckBox.Text = 'Show Grid';
            app.GridCheckBox.Value = true;
            app.Equation1Label = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.Equation1Label.Text = 'Equation 1:';
            app.FunctionEditField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'text');
            app.FunctionEditField.Value = '4x+3y=12';
            app.XMinLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.XMinLabel.Text = 'X Min :';
            app.XMinField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'numeric');
            app.XMinField.Value = -10;
            app.XMaxLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.XMaxLabel.Text = 'X Max :';
            app.XMaxField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'numeric');
            app.XMaxField.Value = 10;
            app.YMinLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.YMinLabel.Text = 'Y Min :';
            app.YMinField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'numeric');
            app.YMinField.Value = -10;
            app.YMaxLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.YMaxLabel.Text = 'Y Max :';
            app.YMaxField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'numeric');
            app.YMaxField.Value = 10;
            app.StatusTextLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.StatusTextLabel.Text = 'Status:';
            app.StatusLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.StatusLabel.Text = 'Ready';
            app.ResetViewButton = uibutton(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.LineColorDropDownLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.LineColorDropDownLabel.Text = 'Line Color ';
            app.ColorDropDown = uidropdown(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.ColorDropDown.Items = {'Auto', 'Blue', 'Red', 'Green', 'Black', 'Custom...'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Auto';
            app.ColorEditField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'text');
            app.ColorEditField.Visible = 'off';
            app.ColorEditField.Value = '#FF5733';
            app.AddColorButton = uibutton(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'push');
            app.AddColorButton.ButtonPushedFcn = createCallbackFcn(app, @AddColorButtonPushed, true);
            app.AddColorButton.Visible = 'off';
            app.AddColorButton.Text = 'Save Custom Color';
            app.DimensionSwitch = uiswitch(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'slider');
            app.DimensionSwitch.Items = {'2D', '3D'};
            app.DimensionSwitch.ValueChangedFcn = createCallbackFcn(app, @DimensionSwitchValueChanged, true);
            app.DimensionSwitch.Value = '2D';
            app.YMaxLabel_2 = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.YMaxLabel_2.Visible = 'off';
            app.YMaxLabel_2.Text = 'Z Max :';
            app.ZMaxField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'numeric');
            app.ZMaxField.Visible = 'off';
            app.ZMaxField.Value = 10;
            app.FormatHintLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.FormatHintLabel.Text = '2D: f(x) or f(x,y)=0 | 3D: f(x,y) or f(x,y,z)=0 ';
            app.YMinLabel_2 = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.YMinLabel_2.Visible = 'off';
            app.YMinLabel_2.Text = 'Z Min :';
            app.ZMinField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'numeric');
            app.ZMinField.Visible = 'off';
            app.ZMinField.Value = -10;
            app.FindRootsButton = uibutton(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'push');
            app.FindRootsButton.ButtonPushedFcn = createCallbackFcn(app, @FindRootsButtonPushed, true);
            app.FindRootsButton.Text = 'Find roots / Intercepts';
            app.RootResultLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.RootResultLabel.Text = 'Root: None Calculated';
            app.Equation1Label_2 = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.Equation1Label_2.Text = 'Equation 2:';
            app.Function2EditField = uieditfield(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'text');
            app.EnableEq2CheckBox = uicheckbox(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.EnableEq2CheckBox.Text = 'Compare / Intersect with Eq 2';
            app.DColormapDropDownLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.DColormapDropDownLabel.Text = '3D Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.ColormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer'};
            app.ColormapDropDown.Value = 'parula';
            app.WireframeCheckBox = uicheckbox(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.WireframeCheckBox.Text = 'Wireframe Mesh Mode';
            app.ExportButton = uibutton(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Plot';
            app.ThemeSwitchLabel = uilabel(app.UIFigureDesignerMultiEquationGraphingNumericalUtility);
            app.ThemeSwitchLabel.Text = 'Theme';
            app.ThemeSwitch = uiswitch(app.UIFigureDesignerMultiEquationGraphingNumericalUtility, 'slider');
            app.ThemeSwitch.Items = {'Dark', 'Light'};
            app.ThemeSwitch.ValueChangedFcn = createCallbackFcn(app, @ThemeSwitchValueChanged, true);
            app.ThemeSwitch.Value = 'Dark';
            % Show the figure after all components are created
            app.UIFigureDesignerMultiEquationGraphingNumericalUtility.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureDesignerMultiEquationGraphingNumericalUtility)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureDesignerMultiEquationGraphingNumericalUtility)
        end
    end
end